classdef gui < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        ResetButton           matlab.ui.control.Button
        GridLayout3           matlab.ui.container.GridLayout
        EnterexternalforceasawrenchEditField  matlab.ui.control.NumericEditField
        EnterexternalforceasawrenchEditFieldLabel  matlab.ui.control.Label
        ExternalforceLabel    matlab.ui.control.Label
        PositioncontrolLabel  matlab.ui.control.Label
        GridLayout2_2         matlab.ui.container.GridLayout
        YEditField_2          matlab.ui.control.NumericEditField
        YLabel_2              matlab.ui.control.Label
        PEditField            matlab.ui.control.NumericEditField
        PLabel                matlab.ui.control.Label
        REditField            matlab.ui.control.NumericEditField
        RLabel                matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        ZEditField            matlab.ui.control.NumericEditField
        ZLabel                matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YLabel                matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XLabel                matlab.ui.control.Label
    end

    properties (Access = public)
        lpitch double = 0; % Last pitch
        lroll double = 0; % Last roll
        lx double = 0; % Last x
        ly double = 0; % Last y
        lyaw double = 0; % Last yaw
        lz double = 0; % Last z
        pitch double = 0; % pitch orientation of the end effector
        robot % Robot
        roll double = 0; % roll orientation of the end effector
        wrench double = 0; % External force applied as a wrench at the end effector
        x double = 0.4521; % x position of the end effector
        y double = -0.15; % y position of the end effector
        yaw double = 0; % yaw orientation of the end effector
        z double = 0.4318; % z position of the end effector
    end
    
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.robot = make_robot();
        end

        % Value changed function: XEditField
        function updateX(comp, event)
            comp.x = comp.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function updateY(comp, event)
            comp.y = comp.YEditField.Value;
            
        end

        % Value changed function: ZEditField
        function updateZ(comp, event)
            comp.z = comp.ZEditField.Value;
            
        end

        % Value changed function: REditField
        function updateRoll(comp, event)
            comp.roll = comp.REditField.Value;
            
        end

        % Value changed function: PEditField
        function updatePitch(comp, event)
            comp.pitch = comp.PEditField.Value;
        end

        % Value changed function: YEditField_2
        function updateYaw(comp, event)
            comp.yaw = comp.YEditField_2.Value;
        end

        % Value changed function: EnterexternalforceasawrenchEditField
        function updateWrench(comp, event)
            comp.wrench = comp.EnterexternalforceasawrenchEditField.Value;
            
        end

        % Button pushed function: ResetButton
        function resetSim(comp, event)
            comp.lx = comp.x;
            comp.ly = comp.y;
            comp.lz = comp.z;
            comp.lroll = comp.roll;
            comp.lpitch = comp.pitch;
            comp.lyaw = comp.yaw;
            comp.wrench = 0;
            comp.robot = make_robot();
            comp.x = 0.4521;
            comp.y = -0.15;
            comp.z = 0.4318;
            comp.lroll = 0;
            comp.lpitch = 0;
            comp.lyaw = 0;
            comp.XEditField.Value = comp.x;
            comp.YEditField.Value = comp.y;
            comp.ZEditField.Value = comp.z;
            comp.REditField.Value = 0;
            comp.PEditField.Value = 0;
            comp.YEditField_2.Value = 0;
            comp.EnterexternalforceasawrenchEditField.Value = 0;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            params.x = comp.x;
            params.y = comp.y;
            params.z = comp.z;
            params.roll = comp.roll;
            params.pitch = comp.pitch;
            params.yaw = comp.yaw;
            params.wrench = comp.wrench;
            params.lx = comp.lx;
            params.ly = comp.ly;
            params.lz = comp.lz;
            params.lroll = comp.lroll;
            params.lpitch = comp.lpitch;
            params.lyaw = comp.lyaw;
            tau = move_robot(comp.robot, params);
            subplot(4,2,[1,3,5,7]);
            set(gcf, 'Name', 'PUMA 560 Simulator');
            display(params.x);
            comp.robot.plot([params.x, params.y, params.z,0,0,0]);
            subplot(4,2,2);
            hold on, grid on;
            plot([1 2 3 4 5], 'Linewidth', 2);
            plot([2 4 6 8 10], 'Linewidth', 2);
            plot([1 3 5 7 9], 'Linewidth', 2);
            plot([1 1 2 3 5], 'Linewidth', 2);
            legend({'Joint 1', 'Joint 2', 'Joint 3'});
            title('Joint Positions');
            set(gca, 'FontSize', 14);
            hold off, grid off;
            subplot(4,2,4);
            hold on, grid on;
            plot([1 2 3 4 5], 'Linewidth', 2);
            plot([2 4 6 8 10], 'Linewidth', 2);
            plot([1 3 5 7 9], 'Linewidth', 2);
            plot([1 1 2 3 5], 'Linewidth', 2);
            legend({'Joint 1', 'Joint 2', 'Joint 3'});
            title('Joint Velocities');
            set(gca, 'FontSize', 14);
            hold off, grid off;
            subplot(4,2,6);
            hold on, grid on;
            plot([1 2 3 4 5], 'Linewidth', 2);
            plot([2 4 6 8 10], 'Linewidth', 2);
            plot([1 3 5 7 9], 'Linewidth', 2);
            plot([1 1 2 3 5], 'Linewidth', 2);
            legend({'Joint 1', 'Joint 2', 'Joint 3'});
            title('Joint Accelerations');
            set(gca, 'FontSize', 14);
            hold off, grid off;
            subplot(4,2,8);
            hold on, grid on;
            plot([1 2 3 4 5], 'Linewidth', 2);
            plot([2 4 6 8 10], 'Linewidth', 2);
            plot([1 3 5 7 9], 'Linewidth', 2);
            plot([1 1 2 3 5], 'Linewidth', 2);
            legend({'Joint 1', 'Joint 2', 'Joint 3'});
            title('Joint Torques');
            set(gca, 'FontSize', 14);
            hold off, grid off;
            comp.lx = comp.x;
            comp.ly = comp.y;
            comp.lz = comp.z;
            comp.lroll = comp.roll;
            comp.lpitch = comp.pitch;
            comp.lyaw = comp.yaw;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 345 256];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.XLabel = uilabel(comp.GridLayout2);
            comp.XLabel.Text = 'X:';
            comp.XEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.XEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateX, true);
            comp.XEditField.Value = 0.4521;
            comp.YLabel = uilabel(comp.GridLayout2);
            comp.YLabel.Text = 'Y:';
            comp.YEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.YEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateY, true);
            comp.YEditField.Value = -0.15;
            comp.ZLabel = uilabel(comp.GridLayout2);
            comp.ZLabel.Text = 'Z:';
            comp.ZEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.ZEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateZ, true);
            comp.ZEditField.Value = 0.4318;
            comp.GridLayout2_2 = uigridlayout(comp.GridLayout);
            comp.RLabel = uilabel(comp.GridLayout2_2);
            comp.RLabel.Text = 'R:';
            comp.REditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.REditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateRoll, true);
            comp.PLabel = uilabel(comp.GridLayout2_2);
            comp.PLabel.Text = 'P:';
            comp.PEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.PEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePitch, true);
            comp.YLabel_2 = uilabel(comp.GridLayout2_2);
            comp.YLabel_2.Text = 'Y:';
            comp.YEditField_2 = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.YEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateYaw, true);
            comp.PositioncontrolLabel = uilabel(comp.GridLayout);
            comp.PositioncontrolLabel.Text = 'Position control:';
            comp.ExternalforceLabel = uilabel(comp.GridLayout);
            comp.ExternalforceLabel.Text = 'External force:';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.EnterexternalforceasawrenchEditFieldLabel = uilabel(comp.GridLayout3);
            comp.EnterexternalforceasawrenchEditFieldLabel.Text = 'Enter external force as a wrench:';
            comp.EnterexternalforceasawrenchEditField = uieditfield(comp.GridLayout3, 'numeric');
            comp.EnterexternalforceasawrenchEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateWrench, true);
            comp.ResetButton = uibutton(comp.GridLayout, 'push');
            comp.ResetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @resetSim, true);
            comp.ResetButton.Text = 'Reset';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end